classdef TickTock_BeauVer1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeautyVerOneUIFigure  matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        Button                matlab.ui.control.Button
        SysInfoLabel          matlab.ui.control.Label
        STARTLabel            matlab.ui.control.Label
        UIAxes2_2             matlab.ui.control.UIAxes
        Button_2              matlab.ui.control.Button
        Label                 matlab.ui.control.Label
    end

    %  1,-200,1080,900 背景可用
    %
    properties (Access = private)
        % 更新时间柱
        Timer_01 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        Button_Down = false; % 按钮状态
        
        % 时间统计
        StartTick = '';   % 计时器启动时间
        EndTick = '';     % 计时器结束时间
        ItemDuration = 0; % 持续时间 min
        ItemName char ;   % 事件
        ItemDetail char;  % 事件详情
        
        % 更行系统显示时间
        Timer_02 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        
    end
    
    methods (Access = private)
        
        function ImageBackGroundTab_01(app)
            try
                imshow('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Wallpaper_97.jpg',...
                    'Parent',app.UIAxes);
            catch EM
                fprintf('error message:%s\n',EM.identifier);
            end
        end
        
        function UpdateSysInfo(app,info)
            if ischar(info)
                app.SysInfoLabel.Text = info;
            end
        end
        
        function InitStates(app)
            app.Button_Down = false;
        end
        
        % 时间柱
        function DrawDurationBar_01(app)
            temp = etime(datevec(datestr(datetime('now'),'yy/mm/dd  HH:MM:SS')),...
                datevec(app.StartTick));
            app.ItemDuration = int32(temp/1); % min
            
            b_1 = barh(app.UIAxes2_2,0.125,app.ItemDuration);
            % 设置柱状图
            b_1.FaceColor = [0.85 0.3 0.1];
            b_1.EdgeColor = [0.85 0.3 0.1];
            b_1.LineWidth = 1.2;
        end
        
        
        % 时间柱时间函数
        function UpdateTickBar(app)
            disp('time bar')
        end
        
        function SystemTimeUpdate(app)
            app.Label.Text = datestr(datetime('now'),'HH:MM:SS');
        end
        
        function KeepItemInfo(app)
            %{
                logpath选择需要摆脱文件夹路径限制
            %}
            logpath = 'D:\Codes\MatlabFiles\Blocks\TickTockFiles';
            logname = 'log.txt';
            fileID = fopen(strcat(logpath,'\',logname),'a+');
            if ~isequal(fileID,0)
                % 时间|事项|详情|持续时间
                StTemp = app.StartTick;
                EtTemp = app.EndTick;
                DutationtTemp = app.ItemDuration;
                ItemNameTemp =  app.ItemName;
                ItemDetailTemp = app.ItemDetail;
                
                fprintf(fileID,'%s\t|\t%s\t|\t%s\t|\t%s\t|\t%d\n',StTemp...
                    ,EtTemp,ItemNameTemp,ItemDetailTemp,DutationtTemp);
                disp('file open succeed...')
            end
            fclose(fileID);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            disp('tick tock beauty version')
            ImageBackGroundTab_01(app)
            info_temp = 'tick tock beauty version';
            UpdateSysInfo(app,info_temp);
            
            % 启动时间柱
            app.Timer_01.TimerFcn = @(~, ~) DrawDurationBar_01(app);
            
            % 启动系统时间显示
            app.Timer_02.TimerFcn = @(~,~)SystemTimeUpdate(app);
            start(app.Timer_02);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.Button_Down;
            State = {'START','STOP'};
            if value
                % 启动计时器状态中
                app.STARTLabel.Text = State{1};
                app.Button.BackgroundColor = [0.47,0.67,0.19];
                app.EndTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                
                if ~isempty(timerfind(app.Timer_01))
                    stop(app.Timer_01);
                end
            else
                app.STARTLabel.Text = State{2};
                app.Button.BackgroundColor = [0.64,0.08,0.18];
                
                % 停止计时器状态中
                app.ItemDuration = 0;
                app.StartTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                start(app.Timer_01);
                
            end
            app.Button_Down = ~value;
        end

        % Close request function: BeautyVerOneUIFigure
        function BeautyVerOneUIFigureCloseRequest(app, event)
            delete(app)
            delete(timerfindall())
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if isequal(app.STARTLabel.Text,'STOP')
                UpdateSysInfo(app,'计时中，按钮不可用')
            else
                if app.ItemDuration < 1
                   	UpdateSysInfo(app,'记录时间<1min')
                else
                    prompt_01 = {'ItemName','ItemDetail'};
                    title_01 = 'KEEP INFO';
                    dims_01 = [1 40];
               	    answer_01 = inputdlg(prompt_01,title_01,dims_01);
               	    if isempty(answer_01)
                      		return;
               	    end
               	    ansPartOne = answer_01{1};
               	    ansPartTwo = answer_01{2};
               	    if isempty(ansPartOne) || isempty(ansPartTwo)
                      		return;
               	    else
                      		app.ItemName = ansPartOne;
                      		app.ItemDetail = ansPartTwo;
               	    end
               	    answer_02 = questdlg('Track the Info',...
                       	'KEEP INFO','YES','NO','NO');
                    
                    if isequal(answer_02,'YES')
                        KeepItemInfo(app);
                        DutationtTemp = app.ItemDuration;
                        ItemNameTemp =  app.ItemName;
                        ItemDetailTemp = app.ItemDetail;
                        info_temp = strcat(ItemNameTemp,'|',...
                            ItemDetailTemp,'|',num2str(DutationtTemp));
                        UpdateSysInfo(app,info_temp);
                        
                        % 清除信息
                        app.ItemName = '';
                        app.ItemDetail = '';
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeautyVerOneUIFigure = uifigure;
            app.BeautyVerOneUIFigure.Name = 'BeautyVerOne';
            app.BeautyVerOneUIFigure.CloseRequestFcn = createCallbackFcn(app, @BeautyVerOneUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BeautyVerOneUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.UIAxes.Visible = 'off';
            app.Button = uibutton(app.BeautyVerOneUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'power_button.png';
            app.Button.Text = '';
            app.SysInfoLabel = uilabel(app.BeautyVerOneUIFigure);
            app.SysInfoLabel.Text = 'SysInfo';
            app.STARTLabel = uilabel(app.BeautyVerOneUIFigure);
            app.STARTLabel.Text = 'START';
            app.UIAxes2_2 = uiaxes(app.BeautyVerOneUIFigure);
            app.UIAxes2_2.XLim = [0 240];
            app.UIAxes2_2.YLim = [0 0.5];
            app.UIAxes2_2.CLim = [0 0.5];
            app.UIAxes2_2.ALim = [0 0.5];
            app.UIAxes2_2.XTick = [0 30 60 90 120 150 180 210 240];
            app.UIAxes2_2.YTick = [];
            app.Button_2 = uibutton(app.BeautyVerOneUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '记录信息';
            app.Label = uilabel(app.BeautyVerOneUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = TickTock_BeauVer1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeautyVerOneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeautyVerOneUIFigure)
        end
    end
end